classdef Mosaic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MosaicUI                   matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        GraphicalButton            matlab.ui.control.Button
        ColorsPanel                matlab.ui.container.Panel
        BrowseButton_2             matlab.ui.control.Button
        ImagePathEditField_2Label  matlab.ui.control.Label
        ImagePathField_2           matlab.ui.control.EditField
        NumberoftilesacrossimagewidthEditFieldLabel_2  matlab.ui.control.Label
        tilesAcrossField_2         matlab.ui.control.NumericEditField
        CreateButton_2             matlab.ui.control.Button
        SaveButton_2               matlab.ui.control.Button
        MapButton                  matlab.ui.control.Button
        NumberofcolorsLabel        matlab.ui.control.Label
        nColorsField               matlab.ui.control.NumericEditField
        OpenButton_2               matlab.ui.control.Button
        MosaicGeneratorLabel       matlab.ui.control.Label
        ColorsButton               matlab.ui.control.Button
        GraphicalPanel             matlab.ui.container.Panel
        ImagePathEditFieldLabel    matlab.ui.control.Label
        ImagePathField             matlab.ui.control.EditField
        BrowseButton               matlab.ui.control.Button
        NumberoftilesacrossimagewidthEditFieldLabel  matlab.ui.control.Label
        tilesAcrossField           matlab.ui.control.NumericEditField
        CreateButton               matlab.ui.control.Button
        NumberoftilesacrossimageheightEditFieldLabel  matlab.ui.control.Label
        tilesDownField             matlab.ui.control.NumericEditField
        SaveButton                 matlab.ui.control.Button
        OpenButton                 matlab.ui.control.Button
        TablePanel                 matlab.ui.container.Panel
        ColorTable                 matlab.ui.control.Table
        XButton                    matlab.ui.control.Button
        ExportButton               matlab.ui.control.Button
        TextColorListBoxLabel      matlab.ui.control.Label
        TextColorListBox           matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        tiles;
        tileHeight;
        tileWidth;
        firstGraphical = 1;
        newTable;
        mosaicImg;
        image;
        tilesAcross = 50;
        colorMap;
        nMap;
        nColors = 8;
        newMosaic = 1;
        newGrid = 0;
    end
    
    methods (Access = private)
        % Function to calculate mosaic tiles dimensions
        function getDim(app)
            if app.tilesAcrossField.Value ~= 1 && ~isempty(app.image)
                value = app.tilesAcrossField.Value;
                app.tilesAcross = value;
                [~, imgWidth, ~] = size(app.image);
                app.image = imresize(app.image,app.tilesAcross*app.tileWidth/imgWidth);
                [h, w, ~] = size(app.image);
                [tilesDown, ~, ~] = GetGridDimensions(value,h,w,app.tileHeight,app.tileWidth);
                app.tilesDownField.Value = tilesDown;
            end
        end
        
        function showImg(app)
            figure('Name','Preview');
            imshow(app.image);
            title('Image');
        end
        
        function tiles = getTiles(app,directory,imageType)
            listing = dir([directory '/*.' imageType]);
            n = size(listing,1);
            if n<1
                uialert(app.MosaicUI,'No valid file types found in directory','Invalid Tiles');
                tiles = {};
            else
                tiles = cell(1,n);
                d = uiprogressdlg(app.MosaicUI,'Title','Please Wait','Message','Loading tiles...');
                pause(0.25)
                for i =1:n
                    d.Value = i/n;
                    d.Message = ['Loading tiles... ' num2str(i) '/' num2str(n)];
                    imageName = listing(i).name;
                    tiles{i} = imread([directory '/' imageName],imageType);
                end
                d.Value = 1;
                d.Message = 'Done';
                pause(0.5)
                close(d);
            end
        end


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.MosaicUI,'center');
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [fileName,filePath]=uigetfile({'*.bmp;*.jpg;*.jpeg;*.png;*.tif;*.tiff' 'Image files';'*.bmp' 'BMP';'*.jpg;*.jpeg' 'JPEG';'*.png' 'PNG';'*.tif;*.tiff' 'TIFF';'*.*' 'All Files'});
            app.MosaicUI.Visible = 0;
            app.MosaicUI.Visible = 1;
            if fileName ~= 0
                app.ImagePathField.Value = strcat(filePath,fileName);
                app.image = imread(app.ImagePathField.Value);
                app.getDim();
                app.CreateButton.Enable = 1;
                app.OpenButton.Enable = 1;
                app.newMosaic = 1;
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            if isempty(app.image)
                uialert(app.MosaicUI,'You need to load an image first','Invalid Image');
            elseif app.tilesAcrossField.Value == 1
                uialert(app.MosaicUI,'Invalid number of tiles','Invalid Dimensions');
            elseif app.newMosaic == 1
                d = uiprogressdlg(app.MosaicUI,'Title','Please Wait','Message','Creating mosaic image...','Indeterminate','on');
                app.mosaicImg = CreateMosaic(app.image,app.tilesAcross,app.tiles);
                close(d);
                app.SaveButton.Enable = 1;
                app.OpenButton.Enable = 1;
                figure('Name','Preview');
                imshow(app.mosaicImg);
                title('Mosaic');
                app.newMosaic = 0;
            else
                figure('Name','Preview');
                imshow(app.mosaicImg);
                title('Mosaic');
            end
        end

        % Value changed function: ImagePathField
        function ImagePathFieldValueChanged(app, event)
            value = app.ImagePathField.Value;
            validFormats = [".bmp",".jpg",".jpeg",".png",".tif",".tiff"];
            if contains(value,validFormats,'IgnoreCase',true)
                app.image = imread(value);
                app.CreateButton.Enable = 1;
                app.OpenButton.Enable = 1;
                app.newMosaic = 1;
                app.getDim();
            end
        end

        % Button pushed function: SaveButton, SaveButton_2
        function SaveButtonPushed(app, event)
            [fileName, filePath] = uiputfile({'*.jpg' 'JPEG image';'*.bmp' 'Bitmap file'; ...
                '*.png' 'Portable Network Graphics file';'*.tif' 'TIFF image'},'Save mosaic image','mosaic.jpg');
            if fileName ~= 0
                imwrite(app.mosaicImg,strcat(filePath,fileName));
            end
        end

        % Value changed function: tilesAcrossField
        function tilesAcrossFieldValueChanged(app, event)
            app.getDim();
            app.newMosaic = 1;
        end

        % Button pushed function: GraphicalButton
        function GraphicalButtonPushed(app, event)
            app.ColorsPanel.Visible = 0;
            app.image = uint8.empty;
            if app.firstGraphical == 1
                app.tiles = getTiles(app,'finalDirectory','jpg');
                app.tileHeight = size(app.tiles{1},1);
                app.tileWidth = size(app.tiles{1},2);
                app.firstGraphical = 0;
            end
            app.newMosaic = 1;
            app.ImagePathField.Value = '';
            app.SaveButton.Enable = 0;
            app.OpenButton.Enable = 0;
            app.CreateButton.Enable = 0;
            app.GraphicalPanel.Visible = 1;
        end

        % Value changed function: tilesAcrossField_2
        function tilesAcrossField_2ValueChanged(app, event)
            app.tilesAcross = app.tilesAcrossField_2.Value;
            app.newMosaic = 1;
        end

        % Button pushed function: CreateButton_2
        function CreateButton_2Pushed(app, event)
            if app.newMosaic == 1
                d = uiprogressdlg(app.MosaicUI,'Title','Please Wait','Message','Creating mosaic image...','Indeterminate','on');
                [app.mosaicImg, app.colorMap, app.nMap] = CreateMosaic2(app.image,app.tilesAcross,app.nColors);
                close(d);
                app.SaveButton_2.Enable = 1;
                app.MapButton.Enable = 1;
                app.newTable = 1;
                app.newMosaic = 0;
            end
            figure('Name','Preview');
            imshow(app.mosaicImg);
            title('Mosaic');
        end

        % Value changed function: nColorsField
        function nColorsFieldValueChanged(app, event)
            app.nColors = app.nColorsField.Value;
            app.newMosaic = 1;
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [fileName,filePath]=uigetfile({'*.bmp;*.jpg;*.jpeg;*.png;*.tif;*.tiff' 'Image files';'*.bmp' 'BMP';'*.jpg;*.jpeg' 'JPEG';'*.png' 'PNG';'*.tif;*.tiff' 'TIFF';'*.*' 'All Files'});
            app.MosaicUI.Visible = 0;
            app.MosaicUI.Visible = 1;
            if fileName ~= 0
                app.ImagePathField_2.Value = strcat(filePath,fileName);
                app.image = imread(app.ImagePathField_2.Value);
                app.OpenButton_2.Enable = 1;
                app.newMosaic = 1;
                app.CreateButton_2.Enable = 1;
            end
        end

        % Button pushed function: MapButton
        function MapButtonPushed(app, event)
            % Checks if it needs to create a new table or just open the table panel
            if  app.newTable == 1
                % Retrieves the number of colors (rows) and sets the background colors of the uitable
                % to the color map (both use the same RGB color format of [0-1])
                rows = size(app.colorMap,1);
                app.ColorTable.BackgroundColor = app.colorMap;
                % Creates an empty cell array with 6 columns that we will fill with the data
                data = cell(rows,6);
                % Converts the (rows) by 1 array containing the number of times each color was used to
                % a string array and then converts it to a cell string to put it in the 3rd column of data
                data(:,3) = cellstr(string(app.nMap));
                % Converts the [0-1] RGB color map to [0-255]. uint8 simply converts the value to unsigned 8-bit
                % integers that can have values from 0-255 because we won't need more
                rgb = uint8(app.colorMap*255+0.5);
                % Converts the RGB uint8 array to a cell array to put it in colums 4 through 6 in data
                data(:,4:6) = num2cell(rgb);
                % Converts the RGB values to hex with a minimum of 2 digits.
                hex = dec2hex(rgb.',2);
                % This loops takes the R, G and B of each color in hex and concatenates them to create the full
                % hex color format with a # in the beginning and puts it in the 2nd column of data
                for i=1:rows
                    data(i,2) = cellstr(cat(2,'#',hex(i*3-2,:),hex(3*i-1,:),hex(3*i,:)));
                end
                % This puts the index of the color in the first column.
                data(:,1)=num2cell(transpose(1:rows));
                % Finally sets the uitable data to the created data and changes app.newTable to 0 until the user
                % creates a new mosaic that requires a new table
                app.ColorTable.Data = data;
                app.newTable = 0;
            end
            app.TablePanel.Visible = 1;
        end

        % Button pushed function: ColorsButton
        function ColorsButtonPushed(app, event)
            app.GraphicalPanel.Visible = 0;
            app.image = uint8.empty;
            app.ImagePathField_2.Value = '';
            app.OpenButton_2.Enable = 0;
            app.SaveButton_2.Enable = 0;
            app.CreateButton_2.Enable = 0;
            app.MapButton.Enable = 0;
            app.ColorsPanel.Visible = 1;
            app.newMosaic = 1;
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.TablePanel.Visible = 0;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Prompts the user to browse for the folder to put the excel file in
            [fileName, filePath] = uiputfile({'*.xlsx' 'Excel file'},'Save color map','Color map.xlsx');
            app.MosaicUI.Visible = 0;
            app.MosaicUI.Visible = 1;
            % Checks if the user actually chose a file and didn't press cancel
            % uiputfile will return 0 if the user cancelled
            if fileName ~= 0
                d = uiprogressdlg(app.MosaicUI,'Title','Please Wait','Message','Creating Excel file...','Indeterminate','on');
                % Gets the number of colors (rows) from the color map
                rows = size(app.colorMap,1);
                % Retrieves the data in the uitable
                table = app.ColorTable.Data;
                % Creates a cell array that will be put in the excel file
                % Note that it's the same as the data in "table" with an extra column that will contain
                % the color, hence it concatenates the table with an empty column in the middle
                data = cat(2,table(:,1),cell(rows,1),table(:,2:end));
                % Adds an extra row at the top that contains the titles of the data
                data = cat(1,cellstr(["#" "Color" "Hex" "Times Used" "Red" "Green" "Blue"]),data);
                % Writes the excel file. The second column will be empty aside from the first row that
                % has the world "Color" in it. The actual colors will be filled in later
                xlswrite(fullfile(filePath,fileName),data);
                % Converts the [0-1] RGB values from the color map to 0-255 values and saves them
                rgb = round(app.colorMap*255+0.5);
                % Opens Microsoft Excel and then opens the workbook from the file we just saved
                Excel = actxserver('excel.application');
                WB = Excel.Workbooks.Open(fullfile(filePath,fileName),0,false);
                % This loop goes through the RGB values of each row and sets the excel cell color to
                % the corresponding RGB color
                for i=1:rows
                    % Excel uses the colors in this format in decimal numbers:
                    % Color = Red + Green*256 + Blue*256^2
                    % (Red, Green and Blue each have a value between 0-255)
                    color = rgb(i,1)+rgb(i,2)*256+rgb(i,3)*256^2;
                    % Puts the color in the corresponding cell (B is the 2nd column and i+1 is the row)
                    WB.Worksheets.Item(1).Range(['B', num2str(i+1)]).Interior.Color = color;
                end
                % Auto-fit columns width to the data for better readability
                WB.Worksheets.Item(1).Range(['A1:G', num2str(rows+1)]).Columns.AutoFit;
                % Save and close the workbook and quits excel
                WB.Save;
                WB.Close;
                Excel.Quit;
                close(d);
                uialert(app.MosaicUI,'File saved successfully','Done','Icon','success');
            end
        end

        % Value changed function: TextColorListBox
        function TextColorListBoxValueChanged(app, event)
            app.ColorTable.ForegroundColor = app.TextColorListBox.Value;
            %uialert(app.MosaicUI,app.TextColorListBox.Value,"test");
        end

        % Button pushed function: OpenButton, OpenButton_2
        function OpenButtonPushed(app, event)
            app.showImg();
        end

        % Value changed function: ImagePathField_2
        function ImagePathField_2ValueChanged(app, event)
            value = app.ImagePathField_2.Value;
            validFormats = [".bmp",".jpg",".jpeg",".png",".tif",".tiff"];
            if contains(value,validFormats,'IgnoreCase',true)
                app.image = imread(value);
                app.OpenButton_2.Enable = 1;
                app.newMosaic = 1;
                app.CreateButton_2.Enable = 1;
            end
        end

        % Callback function
        function GridColorDropDownValueChanged(app, event)
            app.newGrid = 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MosaicUI = uifigure;
            app.MosaicUI.Name = 'Mosaic Creator';
            app.Panel = uipanel(app.MosaicUI);
            app.GraphicalButton = uibutton(app.Panel, 'push');
            app.GraphicalButton.ButtonPushedFcn = createCallbackFcn(app, @GraphicalButtonPushed, true);
            app.GraphicalButton.Text = 'Graphical';
            app.ColorsPanel = uipanel(app.Panel);
            app.ColorsPanel.Title = 'Solid Colors';
            app.BrowseButton_2 = uibutton(app.ColorsPanel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.ImagePathEditField_2Label = uilabel(app.ColorsPanel);
            app.ImagePathEditField_2Label.Text = 'Image Path';
            app.ImagePathField_2 = uieditfield(app.ColorsPanel, 'text');
            app.ImagePathField_2.ValueChangedFcn = createCallbackFcn(app, @ImagePathField_2ValueChanged, true);
            app.NumberoftilesacrossimagewidthEditFieldLabel_2 = uilabel(app.ColorsPanel);
            app.NumberoftilesacrossimagewidthEditFieldLabel_2.Text = 'Number of tiles across image width';
            app.tilesAcrossField_2 = uieditfield(app.ColorsPanel, 'numeric');
            app.tilesAcrossField_2.LowerLimitInclusive = 'off';
            app.tilesAcrossField_2.Limits = [0 1000];
            app.tilesAcrossField_2.RoundFractionalValues = 'on';
            app.tilesAcrossField_2.ValueDisplayFormat = '%.0f';
            app.tilesAcrossField_2.ValueChangedFcn = createCallbackFcn(app, @tilesAcrossField_2ValueChanged, true);
            app.tilesAcrossField_2.Value = 50;
            app.CreateButton_2 = uibutton(app.ColorsPanel, 'push');
            app.CreateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CreateButton_2Pushed, true);
            app.CreateButton_2.Enable = 'off';
            app.CreateButton_2.Text = 'Create Mosaic';
            app.SaveButton_2 = uibutton(app.ColorsPanel, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton_2.Enable = 'off';
            app.SaveButton_2.Text = 'Save';
            app.MapButton = uibutton(app.ColorsPanel, 'push');
            app.MapButton.ButtonPushedFcn = createCallbackFcn(app, @MapButtonPushed, true);
            app.MapButton.Enable = 'off';
            app.MapButton.Text = 'Color Map Table';
            app.NumberofcolorsLabel = uilabel(app.ColorsPanel);
            app.NumberofcolorsLabel.Text = 'Number of colors';
            app.nColorsField = uieditfield(app.ColorsPanel, 'numeric');
            app.nColorsField.LowerLimitInclusive = 'off';
            app.nColorsField.Limits = [0 65536];
            app.nColorsField.RoundFractionalValues = 'on';
            app.nColorsField.ValueDisplayFormat = '%.0f';
            app.nColorsField.ValueChangedFcn = createCallbackFcn(app, @nColorsFieldValueChanged, true);
            app.nColorsField.Value = 8;
            app.OpenButton_2 = uibutton(app.ColorsPanel, 'push');
            app.OpenButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton_2.Enable = 'off';
            app.OpenButton_2.Text = 'Open Image';
            app.MosaicGeneratorLabel = uilabel(app.Panel);
            app.MosaicGeneratorLabel.Text = 'Mosaic Generator';
            app.ColorsButton = uibutton(app.Panel, 'push');
            app.ColorsButton.ButtonPushedFcn = createCallbackFcn(app, @ColorsButtonPushed, true);
            app.ColorsButton.Text = 'Solid Colors';
            app.GraphicalPanel = uipanel(app.Panel);
            app.GraphicalPanel.Title = 'Graphical Mosaic';
            app.GraphicalPanel.Visible = 'off';
            app.ImagePathEditFieldLabel = uilabel(app.GraphicalPanel);
            app.ImagePathEditFieldLabel.Text = 'Image Path';
            app.ImagePathField = uieditfield(app.GraphicalPanel, 'text');
            app.ImagePathField.ValueChangedFcn = createCallbackFcn(app, @ImagePathFieldValueChanged, true);
            app.BrowseButton = uibutton(app.GraphicalPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.NumberoftilesacrossimagewidthEditFieldLabel = uilabel(app.GraphicalPanel);
            app.NumberoftilesacrossimagewidthEditFieldLabel.Text = 'Number of tiles across image width';
            app.tilesAcrossField = uieditfield(app.GraphicalPanel, 'numeric');
            app.tilesAcrossField.LowerLimitInclusive = 'off';
            app.tilesAcrossField.Limits = [0 1000];
            app.tilesAcrossField.RoundFractionalValues = 'on';
            app.tilesAcrossField.ValueDisplayFormat = '%.0f';
            app.tilesAcrossField.ValueChangedFcn = createCallbackFcn(app, @tilesAcrossFieldValueChanged, true);
            app.tilesAcrossField.Value = 50;
            app.CreateButton = uibutton(app.GraphicalPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Enable = 'off';
            app.CreateButton.Text = 'Create Mosaic';
            app.NumberoftilesacrossimageheightEditFieldLabel = uilabel(app.GraphicalPanel);
            app.NumberoftilesacrossimageheightEditFieldLabel.Text = 'Number of tiles across image height';
            app.tilesDownField = uieditfield(app.GraphicalPanel, 'numeric');
            app.tilesDownField.LowerLimitInclusive = 'off';
            app.tilesDownField.Limits = [0 1000];
            app.tilesDownField.RoundFractionalValues = 'on';
            app.tilesDownField.Editable = 'off';
            app.tilesDownField.Value = 1;
            app.SaveButton = uibutton(app.GraphicalPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.OpenButton = uibutton(app.GraphicalPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Enable = 'off';
            app.OpenButton.Text = 'Open Image';
            app.TablePanel = uipanel(app.MosaicUI);
            app.TablePanel.Title = 'Color Table';
            app.TablePanel.Visible = 'off';
            app.ColorTable = uitable(app.TablePanel);
            app.ColorTable.ColumnName = {'Index'; 'Hex'; 'Number Used'; 'Red'; 'Green'; 'Blue'};
            app.ColorTable.RowName = {};
            app.XButton = uibutton(app.TablePanel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.ExportButton = uibutton(app.TablePanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export to Excel';
            app.TextColorListBoxLabel = uilabel(app.TablePanel);
            app.TextColorListBoxLabel.Text = 'Text Color';
            app.TextColorListBox = uilistbox(app.TablePanel);
            app.TextColorListBox.Items = {'Black', 'White', 'Grey'};
            app.TextColorListBox.ItemsData = {'k', 'w', '0.5 0.5 0.5'};
            app.TextColorListBox.ValueChangedFcn = createCallbackFcn(app, @TextColorListBoxValueChanged, true);
            app.TextColorListBox.Value = 'k';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Mosaic

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MosaicUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MosaicUI)
        end
    end
end